classdef fuzykost < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        PesanLabel          matlab.ui.control.Label
        KeamananEF          matlab.ui.control.NumericEditField
        HargaLabel_4        matlab.ui.control.Label
        FasilitasEF         matlab.ui.control.NumericEditField
        HargaLabel_2        matlab.ui.control.Label
        Label               matlab.ui.control.Label
        HasilKeputusan      matlab.ui.control.EditField
        HargaLabel          matlab.ui.control.Label
        OutputLabel         matlab.ui.control.Label
        ResetButton         matlab.ui.control.Button
        PemilhanButton      matlab.ui.control.Button
        LuasEF              matlab.ui.control.NumericEditField
        LuasLabel           matlab.ui.control.Label
        JarakEF             matlab.ui.control.NumericEditField
        JarakLabel          matlab.ui.control.Label
        HargaEF             matlab.ui.control.NumericEditField
        HargaLabel_3        matlab.ui.control.Label
        InputLabel          matlab.ui.control.Label
        PemilihanKostLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PemilhanButton
        function PemilhanButtonPushed(app, event)
            % Ditambahkan
            fis = readfis('Ibnu');
            Harga = app.HargaEF.Value;
            Jarak = app.JarakEF.Value;
            Luas = app.LuasEF.Value;
            Fasilitas = app.FasilitasEF.Value;
            Keamanan = app.KeamananEF.Value;

            Keputusan = evalfis(fis, [Harga Jarak Luas Fasilitas Keamanan]);

            inputData = [Harga Jarak Luas Fasilitas Keamanan];
            disp(inputData);

            app.HasilKeputusan.Value = sprintf('%.0f', Keputusan);

            if Keputusan < 40
                app.Label.Text = "Tidak Sewa";
            elseif Keputusan <= 60
                app.Label.Text = "Dipertimbangkan";
            else
                app.Label.Text= "Langsung Sewa";
            end

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Ditambahakan
            app.JarakEF.Value = 1;
            app.HargaEF.Value = 1;
            app.LuasEF.Value = 1;
            app.FasilitasEF.Value = 1;
            app.KeamananEF.Value = 1;
            app.HasilKeputusan.Value = '0';
        end

        % Value changed function: HargaEF
        function HargaEFValueChanged(app, event)
            value = app.HargaEF.Value;
            if value > 800
                app.HargaEF.Value = 1;
                app.PesanLabel.Text = "PESAN: Harga tidak boleh lebih dari 800!";
            elseif value < 1
                app.HargaEF.Value = 1;
                app.PesanLabel.Text = "PESAN: Harga tidak boleh kurang dari 1!";
            end
        end

        % Value changed function: JarakEF
        function JarakEFValueChanged(app, event)
            value = app.JarakEF.Value;
            if value > 6
                app.JarakEF.Value = 1;
                app.PesanLabel.Text = "PESAN: Jarak tidak boleh lebih dari 6!";
            elseif value < 1
                app.JarakEF.Value = 1;
                app.PesanLabel.Text = "PESAN: Jarak tidak boleh kurang dari 1!";
            end
        end

        % Value changed function: LuasEF
        function LuasEFValueChanged(app, event)
            value = app.LuasEF.Value;
            if value > 16
                app.LuasEF.Value = 1;
                app.PesanLabel.Text = "PESAN: Luas tidak boleh lebih dari 16!";
            elseif value < 0
                app.LuasEF.Value = 0;
                app.PesanLabel.Text = "PESAN: Luas tidak boleh kurang dari 0!";
            end
        end

        % Callback function: not associated with a component
        function sdasasg(app, event)
            value = app.FasilitasEF.Value;
            
        end

        % Value changed function: FasilitasEF
        function FasilitasEFValueChanged(app, event)
            value = app.FasilitasEF.Value;
            if value > 6
                app.FasilitasEF.Value = 1;
                app.PesanLabel.Text = "PESAN: fasilitas tidak boleh lebih dari 6!";
            elseif value < 0
                app.FasilitasEF.Value = 0;
                app.PesanLabel.Text = "PESAN: fasilitas tidak boleh kurang dari 0!";
            end
        end

        % Value changed function: KeamananEF
        function KeamananEFValueChanged(app, event)
            value = app.KeamananEF.Value;
            if value > 6
                app.KeamananEF.Value = 1;
                app.PesanLabel.Text = "PESAN: keamanan tidak boleh lebih dari 6!";
            elseif value < 0
                app.KeamananEF.Value = 0;
                app.PesanLabel.Text = "PESAN: keamanan tidak boleh kurang dari 0!";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.PemilihanKostLabel = uilabel(app.GridLayout);
            app.PemilihanKostLabel.Tooltip = {'Obi Agustina'; '201011400884'; '07TPLP013'};
            app.PemilihanKostLabel.Text = 'Pemilihan Kost';
            app.InputLabel = uilabel(app.GridLayout);
            app.InputLabel.Text = 'Input';
            app.HargaLabel_3 = uilabel(app.GridLayout);
            app.HargaLabel_3.Text = 'Harga 1 -800';
            app.HargaEF = uieditfield(app.GridLayout, 'numeric');
            app.HargaEF.ValueChangedFcn = createCallbackFcn(app, @HargaEFValueChanged, true);
            app.HargaEF.Tooltip = {'Harga : [ 1-170 ]'};
            app.JarakLabel = uilabel(app.GridLayout);
            app.JarakLabel.Text = 'Jarak 1 - 6';
            app.JarakEF = uieditfield(app.GridLayout, 'numeric');
            app.JarakEF.ValueChangedFcn = createCallbackFcn(app, @JarakEFValueChanged, true);
            app.JarakEF.Tooltip = {'jarak: [ 1-117 ]'};
            app.LuasLabel = uilabel(app.GridLayout);
            app.LuasLabel.Text = 'Luas 1- 16';
            app.LuasEF = uieditfield(app.GridLayout, 'numeric');
            app.LuasEF.ValueChangedFcn = createCallbackFcn(app, @LuasEFValueChanged, true);
            app.LuasEF.Tooltip = {'luas: [ 0-10 ]'};
            app.PemilhanButton = uibutton(app.GridLayout, 'push');
            app.PemilhanButton.ButtonPushedFcn = createCallbackFcn(app, @PemilhanButtonPushed, true);
            app.PemilhanButton.Tag = 'tombolHitung';
            app.PemilhanButton.Text = 'Pemilhan';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.OutputLabel = uilabel(app.GridLayout);
            app.OutputLabel.Text = 'Output';
            app.HargaLabel = uilabel(app.GridLayout);
            app.HargaLabel.Text = 'Keputusan';
            app.HasilKeputusan = uieditfield(app.GridLayout, 'text');
            app.HasilKeputusan.Editable = 'off';
            app.HasilKeputusan.Tooltip = {''};
            app.HasilKeputusan.Placeholder = '0';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'Hasil : ';
            app.HargaLabel_2 = uilabel(app.GridLayout);
            app.HargaLabel_2.Text = 'Fasilitas 1- 6';
            app.FasilitasEF = uieditfield(app.GridLayout, 'numeric');
            app.FasilitasEF.ValueChangedFcn = createCallbackFcn(app, @FasilitasEFValueChanged, true);
            app.FasilitasEF.Tooltip = {'fasilitas: [ 1-170 ]'};
            app.HargaLabel_4 = uilabel(app.GridLayout);
            app.HargaLabel_4.Text = 'Keamanan 1 -6';
            app.KeamananEF = uieditfield(app.GridLayout, 'numeric');
            app.KeamananEF.ValueChangedFcn = createCallbackFcn(app, @KeamananEFValueChanged, true);
            app.KeamananEF.Tooltip = {'keamanan: [ 1-170 ]'};
            app.PesanLabel = uilabel(app.GridLayout);
            app.PesanLabel.Text = 'PESAN: Kosong';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fuzykost

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end